classdef Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        ModeSwitchLabel      matlab.ui.control.Label
        ModeSwitch           matlab.ui.control.Switch
        ResolveMeshButton    matlab.ui.control.Button
        AlphaEditFieldLabel  matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        ImportButton         matlab.ui.control.Button
        MorphSlider          matlab.ui.control.Slider
        MorphSliderLabel     matlab.ui.control.Label
        AddButton_2          matlab.ui.control.Button
        LayerSpinner         matlab.ui.control.Spinner
        LayerSpinnerLabel    matlab.ui.control.Label
        ShapesListBox        matlab.ui.control.ListBox
        SetShapeButton       matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ObjectsListBox       matlab.ui.control.ListBox
        Area_ROI             matlab.ui.control.UIAxes
        Area_Img             matlab.ui.control.UIAxes
        Area_Back            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        objs % Description
        current % Description
        morph % Description
        mode % Description
    end
    
    methods (Access = private)
        
        function dispMeshes(app)
            if app.current ~= 0
                image(app.Area_Img,app.objs{app.current}{2},"AlphaData",app.objs{app.current}{3});
                points = app.objs{app.current}{5};
                mesh = app.objs{app.current}{6};                
                rearrangeROI(app,points);
                plotEdges(app,points,mesh);
            end
        end
        
        function resolveMeshes(app)
            if app.current ~= 0
                ROI = app.objs{app.current}{4};
                points_num = length(ROI);
                points = zeros(points_num,2);
                for i = 1:points_num
                    points(i,1) = ROI{i}.Position(1);
                    points(i,2) = ROI{i}.Position(2);
                end
                app.objs{app.current}{5} = points;
                mesh = point2mesh(points,app.AlphaEditField.Value);
                app.objs{app.current}{6} = mesh;
            end
        end
        
        function setShape(app)
            ROI = app.objs{app.current}{4};
            points_num = length(ROI);
            points = zeros(points_num,2);
            for i = 1:points_num
                points(i,1) = ROI{i}.Position(1);
                points(i,2) = ROI{i}.Position(2);
            end
            app.objs{app.current}{7} = points;
        end
        
        function plotEdges(app,points,mesh)
            [mesh_num,~] = size(mesh);
            hold(app.Area_Img,"on");
            for i = 1:mesh_num
                idxs = mesh(i,:);
                plot(app.Area_Img,[points(idxs,1); points(idxs(1),1)],[points(idxs,2); points(idxs(1),2)],color=[0 1 0])
            end
            hold(app.Area_Img,"off");
        end
        
        function dispShape(app)
            [img,mask,morph_points] = obj2img(app.objs{app.current},app.morph); 
            image(app.Area_Img,img,"AlphaData",mask);
            mesh = app.objs{app.current}{6};  
            plotEdges(app,morph_points,mesh);
        end
        
        function rearrangeROI(app,points)
            cla(app.Area_ROI);
            [points_num, ~] = size(points);
            for i = 1:points_num
                app.objs{app.current}{4}{i} = [];
                app.objs{app.current}{4}{i} = drawpoint(app.Area_ROI,Position=[points(i,1) points(i,2)]);   
            end
        end
        
        function setupArea(app)
            [W,H,~]=size(app.objs{length(app.objs)}{2});
            
            app.Area_Img.XLim = [-inf,inf];
            app.Area_Img.YLim = [-inf,inf];
            
            app.Area_Back.XLim = [-inf,inf];
            app.Area_Back.YLim = [-inf,inf];
            
            app.Area_ROI.YDir = 'reverse';    
            app.Area_ROI.XLim = [1,W];
            app.Area_ROI.YLim = [1,H];
            
            image(app.Area_Back,imread("imgs\bk.png"));
            
            cla(app.Area_ROI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.objs = {};
            app.current = 0;
            app.morph = 1;
            app.ModeSwitch.Value = "Mesh";
            pbaspect(app.Area_Img,[1 1 1]);
            pbaspect(app.Area_ROI,[1 1 1]);
            pbaspect(app.Area_Back,[1 1 1]);
            %app.ObjectsListBox.Items(1) = {"aaa"};
        end

        % Button pushed function: AddButton
        function addObj(app, event)
            [file,path] = uigetfile("*.png");
            
            if file ~= 0
                app.current = length(app.objs)+1;
                [img,~,mask]=imread(append(path,file));
                app.objs{app.current} = {file,img,mask,{}};
                app.objs{app.current}{7} = [];
                %item = string(file);
                setupArea(app);
                image(app.Area_Img,app.objs{app.current}{2},"AlphaData",app.objs{app.current}{3});
            end
            dispMeshes(app);
        end

        % Button pushed function: ResolveMeshButton
        function ResolveMeshesButtonPushed(app, event)
            if app.ModeSwitch.Value == "Shape"
                points = app.objs{app.current}{5};
                rearrangeROI(app,points); 
            end
            
            resolveMeshes(app);
            dispMeshes(app);
            
            if app.ModeSwitch.Value == "Shape"    
                app.ModeSwitch.Value = "Mesh"; 
            else
                app.objs{app.current}{7} = [];
            end
            
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.AlphaEditField.Value;
        end

        % Button down function: Area_ROI
        function Area_ROIButtonDown(app, event)
            if app.current ~= 0 && app.ModeSwitch.Value == "Mesh"
                app.objs{app.current}{4}{1+length(app.objs{app.current}{4})} = drawpoint(app.Area_ROI);   
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            if file ~= 0
                OBJS = app.objs;
                save(append(path,file),"OBJS",'-mat');
            end
        end

        % Button pushed function: SetShapeButton
        function SetShapeButtonPushed(app, event)
            if app.current ~= 0
                app.ModeSwitch.Value = "Shape";
                setShape(app);
                dispShape(app);
            end
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
            app.morph = event.Value;
            dispShape(app);
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if app.current~=0
                if app.ModeSwitch.Value == "Shape"
                    resolveMeshes(app);
                    if ~isempty(app.objs{app.current}{7})
                        points = app.objs{app.current}{7};
                        rearrangeROI(app,points);
                    end
                    setShape(app);
                    dispShape(app);
                else
                    if ~isempty(app.objs{app.current}{5})
                        points = app.objs{app.current}{5};
                        rearrangeROI(app,points);
                    end
                    dispMeshes(app);
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
              [file,path] = uigetfile();
              if file ~= 0
                  load(append(path,file),"-mat","OBJS");
                  app.objs = OBJS;
                  app.current = length(app.objs);
                  setupArea(app);
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Area_Back = uiaxes(app.GridLayout);
            xlabel(app.Area_Back, 'X')
            ylabel(app.Area_Back, 'Y')
            zlabel(app.Area_Back, 'Z')
            app.Area_Img = uiaxes(app.GridLayout);
            xlabel(app.Area_Img, 'X')
            ylabel(app.Area_Img, 'Y')
            zlabel(app.Area_Img, 'Z')
            app.Area_ROI = uiaxes(app.GridLayout);
            xlabel(app.Area_ROI, 'X')
            ylabel(app.Area_ROI, 'Y')
            zlabel(app.Area_ROI, 'Z')
            app.Area_ROI.ButtonDownFcn = createCallbackFcn(app, @Area_ROIButtonDown, true);
            app.ObjectsListBox = uilistbox(app.GridLayout);
            app.ObjectsListBox.Items = {'項目 1', '項目 2'};
            app.ObjectsListBox.Value = '項目 1';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addObj, true);
            app.AddButton.Text = 'Add';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SetShapeButton = uibutton(app.GridLayout, 'push');
            app.SetShapeButton.ButtonPushedFcn = createCallbackFcn(app, @SetShapeButtonPushed, true);
            app.SetShapeButton.Text = 'Set Shape';
            app.ShapesListBox = uilistbox(app.GridLayout);
            app.ShapesListBox.Items = {};
            app.ShapesListBox.Value = {};
            app.LayerSpinnerLabel = uilabel(app.GridLayout);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.GridLayout);
            app.AddButton_2 = uibutton(app.GridLayout, 'push');
            app.AddButton_2.Text = 'Add';
            app.MorphSliderLabel = uilabel(app.GridLayout);
            app.MorphSliderLabel.Text = 'Morph';
            app.MorphSlider = uislider(app.GridLayout);
            app.MorphSlider.Limits = [0 1];
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSlider.Value = 1;
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AlphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = Inf;
            app.AlphaEditFieldLabel = uilabel(app.GridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.ResolveMeshButton = uibutton(app.GridLayout, 'push');
            app.ResolveMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveMeshesButtonPushed, true);
            app.ResolveMeshButton.Text = 'Resolve Mesh';
            app.ModeSwitch = uiswitch(app.GridLayout, 'slider');
            app.ModeSwitch.Items = {'Mesh', 'Shape'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Mesh';
            app.ModeSwitchLabel = uilabel(app.GridLayout);
            app.ModeSwitchLabel.Text = 'Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end